classdef newproject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        BeepButton             matlab.ui.control.Button
        WhiteNoiseButton       matlab.ui.control.Button
        PlayFileButton         matlab.ui.control.Button
        AddNoiseButton         matlab.ui.control.Button
        RecordButton           matlab.ui.control.Button
        IncreaseButton         matlab.ui.control.Button
        DecreaseButton         matlab.ui.control.Button
        EchoButton             matlab.ui.control.Button
        RemoveNoiseButton      matlab.ui.control.Button
        ReverseButton          matlab.ui.control.Button
        VolumeControlButton    matlab.ui.control.StateButton
        IncreaseButton_2       matlab.ui.control.Button
        DecreaseButton_2       matlab.ui.control.Button
        SpeedControlButton     matlab.ui.control.StateButton
        StopButton             matlab.ui.control.Button
        PlotButton             matlab.ui.control.Button
        SubtractSignalsButton  matlab.ui.control.Button
        MultiplySignalsButton  matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes3                matlab.ui.control.UIAxes
        Label                  matlab.ui.control.Label
    end


    properties (Access = private)
    AudioFilePath
    AudioData
    Fs
    RecordedAudio
    OriginalAudio   
    EchoedAudio     
    CleanedAudio    
    AdjustedAudio  
    AudioFilePath1   
    AudioFilePath2    
    Signal1           
    Signal2            
    ResultSignal      
    Player           
    AudioSignal 
    end

    methods (Access = private)
    
        function plotSignals(app)
            subplot(3, 1, 1);
            plot(app.UIAxes, app.Signal1, 'b');
            title('Signal 1');
            xlabel('Time');
            ylabel('Amplitude');

            subplot(3, 1, 2);
            plot(app.UIAxes2, app.Signal2, 'r');
            title('Signal 2');
            xlabel('Time');
            ylabel('Amplitude');

            subplot(3, 1, 3);
            plot(app.UIAxes3, app.ResultSignal, 'g');
            title('Result Signal');
            xlabel('Time');
            ylabel('Amplitude');
        end
        
    end


    methods (Access = private)

        % Button pushed function: BeepButton
        function BeepButtonPushed(app, event)
            beep;
           % Fs = 22050;
           % t = 0:1/22050:2*pi;
           % beeep = sin(2*pi*400*t);
           % sound(beeep, Fs);
        end

        % Button pushed function: WhiteNoiseButton
        function WhiteNoiseButtonPushed(app, event)
            Fs = 44100;
            t = 0:1/Fs:1;
            noise = randn(size(t));
            sound(noise, Fs);         
        end

        % Button pushed function: PlayFileButton
        function PlayFileButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.wav'; '*.mp3'}, 'Select an audio file');
            if filename ~= 0
                app.AudioFilePath = fullfile(filepath, filename);
                [app.AudioData, app.Fs] = audioread(app.AudioFilePath);
                
                % Play the loaded audio
                if ~isempty(app.AudioData)
                    sound(app.AudioData, app.Fs);
                end
            end
        end

        % Button pushed function: AddNoiseButton
        function AddNoiseButtonPushed(app, event)
    [filename, filepath] = uigetfile({'*.wav'; '*.mp3'}, 'Select an audio file');
    if filename ~= 0
        app.AudioFilePath = fullfile(filepath, filename);
        [audioData, Fs] = audioread(app.AudioFilePath);
        
        % Add random noise to the loaded audio
        noise_level = 0.1;
        noisyAudio = audioData + noise_level * randn(size(audioData));
        
        % Play the noisy audio
        sound(noisyAudio, Fs);
    end
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            % Record the audio signal
            duration = 4;
            app.RecordedAudio = audiorecorder(44100, 16, 1);
            recordblocking(app.RecordedAudio, duration);
            
            % Save the recorded audio to a file on D: drive
            audio_data = getaudiodata(app.RecordedAudio);
            Fs = app.RecordedAudio.SampleRate;
            output_filepath = fullfile('D:\', 'recorded_audio.wav');
            audiowrite(output_filepath, audio_data, Fs);    
        end

        % Button pushed function: EchoButton
        function EchoFileButtonPushed(app, event)
            % Open a file dialog to select an audio file
            [filename, filepath] = uigetfile({'*.wav'; '*.mp3'}, 'Select an audio file');
            if filename ~= 0
                app.AudioFilePath = fullfile(filepath, filename);
                [app.OriginalAudio, app.Fs] = audioread(app.AudioFilePath);
            
                % Apply an echo effect to the original audio
                if ~isempty(app.OriginalAudio)
                    delay = 0.5 * app.Fs;
                    decay_factor = 0.5;
                    app.EchoedAudio = app.OriginalAudio;
                    
                    % Create echo by adding a delayed version of the original audio
                    app.EchoedAudio((delay + 1):end) = app.EchoedAudio((delay + 1):end) + ...
                        decay_factor * app.OriginalAudio(1:end - delay);
            
                    % Play the echoed audio
                    sound(app.EchoedAudio, app.Fs);
                end
            end      
        end

        % Button pushed function: RemoveNoiseButton
        function NoiseRemovalButtonPushed(app, event)
              % Open a file dialog to select an audio file
            [filename, filepath] = uigetfile({'*.wav'; '*.mp3'}, 'Select an audio file');
            if filename ~= 0
                app.AudioFilePath = fullfile(filepath, filename);
                [app.OriginalAudio, app.Fs] = audioread(app.AudioFilePath);
            
                % Remove noise from the original audio
                if ~isempty(app.OriginalAudio)
                    % let's use a simple low-pass filter
                    cutoff_frequency = 2000;
                    filter_order = 20;
                    filter_object = designfilt('lowpassfir', 'FilterOrder', filter_order, 'CutoffFrequency', cutoff_frequency, 'SampleRate', app.Fs);
                    app.CleanedAudio = filter(filter_object, app.OriginalAudio);
            
                    % Play the cleaned audio
                    sound(app.CleanedAudio, app.Fs);
                end
             end  
        end

        % Button pushed function: IncreaseButton
        function IncreaseVolumeButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.wav'; '*.mp3'}, 'Select an audio file');
            if filename ~= 0
                app.AudioFilePath = fullfile(filepath, filename);
                [app.OriginalAudio, app.Fs] = audioread(app.AudioFilePath);
            
                % Increase the volume of the original audio
                if ~isempty(app.OriginalAudio)
                    volume_multiplier = 10000000;
                    app.AdjustedAudio = app.OriginalAudio * volume_multiplier;
            
                    % Play the adjusted audio
                    sound(app.AdjustedAudio, app.Fs);
                end
            end
        end

        % Button pushed function: DecreaseButton
        function DecreaseVolumeButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.wav'; '*.mp3'}, 'Select an audio file');
            if filename ~= 0
                app.AudioFilePath = fullfile(filepath, filename);
                [app.OriginalAudio, app.Fs] = audioread(app.AudioFilePath);
            
                % Decrease the volume of the original audio
                if ~isempty(app.OriginalAudio)
                    volume_multiplier = 0.5;
                    app.AdjustedAudio = app.OriginalAudio * volume_multiplier;
            
                    % Play the adjusted audio
                    sound(app.AdjustedAudio, app.Fs);
                end
            end      
        end

        % Button pushed function: DecreaseButton_2
        function DecreaseSpeedButtonPushed(app, event)
            
    % Open a file dialog to select an audio file
    [filename, filepath] = uigetfile({'*.wav'; '*.mp3'}, 'Select an audio file');
    if filename ~= 0
        app.AudioFilePath = fullfile(filepath, filename);
        [app.OriginalAudio, app.Fs] = audioread(app.AudioFilePath);
            
        % Decrease the speed of the original audio
        if ~isempty(app.OriginalAudio)
            % You can adjust the speed multiplier as needed
            speed_multiplier = .5;
            
           %%% app.AdjustedAudio = resample(app.OriginalAudio, round(app.Fs * speed_multiplier), app.Fs);
            
            % Calculate the new indices based on speed
            new_indices = round(1:speed_multiplier:length(app.OriginalAudio));
            
            % Ensure indices are within the valid range
            new_indices = min(max(new_indices, 1), length(app.OriginalAudio));
            
            % Resample the audio
            app.AdjustedAudio = app.OriginalAudio(new_indices);
            
            % Play the adjusted audio
            sound(app.AdjustedAudio, app.Fs);
            
         end
    end
        end

        % Button pushed function: IncreaseButton_2
        function IncreaseSpeedButtonPushed(app, event)
            
    % Open a file dialog to select an audio file
    [filename, filepath] = uigetfile({'*.wav'; '*.mp3'}, 'Select an audio file');
    if filename ~= 0
        app.AudioFilePath = fullfile(filepath, filename);
        [app.OriginalAudio, app.Fs] = audioread(app.AudioFilePath);
            
        % Increase the speed of the original audio
        if ~isempty(app.OriginalAudio)
            speed_multiplier = 1.5;
            
           %%% app.AdjustedAudio = resample(app.OriginalAudio, round(app.Fs * speed_multiplier), app.Fs);
            
            
            % Calculate the new indices based on speed
            new_indices = round(1:speed_multiplier:length(app.OriginalAudio));
            
            % Ensure indices are within the valid range
            new_indices = min(max(new_indices, 1), length(app.OriginalAudio));
            
            % Resample the audio
            app.AdjustedAudio = app.OriginalAudio(new_indices);
            
            % Play the adjusted audio
            sound(app.AdjustedAudio, app.Fs);
        end
    end
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.wav'; '*.mp3'}, 'Select an audio file');
            if filename ~= 0
                app.AudioFilePath1 = fullfile(filepath, filename);
                [app.Signal1, app.Fs] = audioread(app.AudioFilePath1);
            end        
            plotSignals(app) ;
        end

        % Button pushed function: SubtractSignalsButton
        function SubtractButtonPushed(app, event)
                      % Open a file dialog to select the first audio file
            [filename, filepath] = uigetfile({'*.wav'; '*.mp3'}, 'Select the first audio file');
            if filename ~= 0
                app.AudioFilePath1 = fullfile(filepath, filename);
                [app.Signal1, app.Fs] = audioread(app.AudioFilePath1);
            
                % Open a file dialog to select the second audio file
                [filename2, filepath2] = uigetfile({'*.wav'; '*.mp3'}, 'Select the second audio file');
                if filename2 ~= 0
                    app.AudioFilePath2 = fullfile(filepath2, filename2);
                    [app.Signal2, ~] = audioread(app.AudioFilePath2);
            
                    % Perform subtraction
                    if length(app.Signal1) == length(app.Signal2)
                        app.ResultSignal = app.Signal1 - app.Signal2;
              
                        % Play the loaded audio
                        if ~isempty(app.ResultSignal)
                          sound(app.ResultSignal, app.Fs);
                        end
            
                        % Plot the signals
                        plotSignals(app);
                    end
                end
            end
        end

        % Button pushed function: MultiplySignalsButton
        function MultiplyButtonPushed(app, event)
            % Open a file dialog to select the first audio file
            [filename, filepath] = uigetfile({'*.wav'; '*.mp3'}, 'Select the first audio file');
            if filename ~= 0
                app.AudioFilePath1 = fullfile(filepath, filename);
                [app.Signal1, app.Fs] = audioread(app.AudioFilePath1);
            
                % Open a file dialog to select the second audio file
                [filename2, filepath2] = uigetfile({'*.wav'; '*.mp3'}, 'Select the second audio file');
                if filename2 ~= 0
                    app.AudioFilePath2 = fullfile(filepath2, filename2);
                    [app.Signal2, ~] = audioread(app.AudioFilePath2);
            
                    % Perform multiplication
                    if length(app.Signal1) == length(app.Signal2)
                        app.ResultSignal = app.Signal1 .* app.Signal2;
            
                        % Play the loaded audio
                        if ~isempty(app.ResultSignal)
                          sound(app.ResultSignal, app.Fs);
                        end
            
                        % Plot the signals
                        plotSignals(app);
                    end
                end
            end         
        end

        % Button pushed function: ReverseButton
        function ReverseButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.wav'; '*.mp3'}, 'Select an audio file');
            if filename ~= 0
    app.AudioFilePath = fullfile(filepath, filename);
    [app.AudioSignal, app.Fs] = audioread(app.AudioFilePath);
            
    % Reverse the audio signal
    reversed_signal = flipud(app.AudioSignal);
            
    % Play the reversed audio
    if ~isempty(reversed_signal)
        sound(reversed_signal, app.Fs);
    end
            end
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.BeepButton = uibutton(app.UIFigure, 'push');
            app.BeepButton.ButtonPushedFcn = createCallbackFcn(app, @BeepButtonPushed, true);
            app.BeepButton.Text = 'Beep';
            app.WhiteNoiseButton = uibutton(app.UIFigure, 'push');
            app.WhiteNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @WhiteNoiseButtonPushed, true);
            app.WhiteNoiseButton.Text = 'White Noise';
            app.PlayFileButton = uibutton(app.UIFigure, 'push');
            app.PlayFileButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFileButtonPushed, true);
            app.PlayFileButton.Text = 'Play File';
            app.AddNoiseButton = uibutton(app.UIFigure, 'push');
            app.AddNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButtonPushed, true);
            app.AddNoiseButton.Text = 'Add Noise';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.IncreaseButton = uibutton(app.UIFigure, 'push');
            app.IncreaseButton.ButtonPushedFcn = createCallbackFcn(app, @IncreaseVolumeButtonPushed, true);
            app.IncreaseButton.Text = 'Increase';
            app.DecreaseButton = uibutton(app.UIFigure, 'push');
            app.DecreaseButton.ButtonPushedFcn = createCallbackFcn(app, @DecreaseVolumeButtonPushed, true);
            app.DecreaseButton.Text = 'Decrease';
            app.EchoButton = uibutton(app.UIFigure, 'push');
            app.EchoButton.ButtonPushedFcn = createCallbackFcn(app, @EchoFileButtonPushed, true);
            app.EchoButton.Text = 'Echo';
            app.RemoveNoiseButton = uibutton(app.UIFigure, 'push');
            app.RemoveNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseRemovalButtonPushed, true);
            app.RemoveNoiseButton.Text = 'Remove Noise';
            app.ReverseButton = uibutton(app.UIFigure, 'push');
            app.ReverseButton.ButtonPushedFcn = createCallbackFcn(app, @ReverseButtonPushed, true);
            app.ReverseButton.Text = 'Reverse';
            app.VolumeControlButton = uibutton(app.UIFigure, 'state');
            app.VolumeControlButton.Text = 'Volume Control';
            app.IncreaseButton_2 = uibutton(app.UIFigure, 'push');
            app.IncreaseButton_2.ButtonPushedFcn = createCallbackFcn(app, @IncreaseSpeedButtonPushed, true);
            app.IncreaseButton_2.Text = 'Increase';
            app.DecreaseButton_2 = uibutton(app.UIFigure, 'push');
            app.DecreaseButton_2.ButtonPushedFcn = createCallbackFcn(app, @DecreaseSpeedButtonPushed, true);
            app.DecreaseButton_2.Text = 'Decrease';
            app.SpeedControlButton = uibutton(app.UIFigure, 'state');
            app.SpeedControlButton.Text = 'Speed Control';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.Text = 'Stop';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SubtractSignalsButton = uibutton(app.UIFigure, 'push');
            app.SubtractSignalsButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractButtonPushed, true);
            app.SubtractSignalsButton.Text = 'Subtract Signals';
            app.MultiplySignalsButton = uibutton(app.UIFigure, 'push');
            app.MultiplySignalsButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplyButtonPushed, true);
            app.MultiplySignalsButton.Text = 'Multiply Signals';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'signal 1');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'signal 2');
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Y');
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'result');
            xlabel(app.UIAxes3, 'X');
            ylabel(app.UIAxes3, 'Y');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '-------------------------------------------------------------------------------------------------------------------------------';
        end
    end

    methods (Access = public)

        % Construct app
        function app = newproject()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end